classdef OptimizationAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AgentEditFieldLabel         matlab.ui.control.Label
        AgentEditField              matlab.ui.control.NumericEditField
        MaxIterationEditFieldLabel  matlab.ui.control.Label
        MaxIterationEditField       matlab.ui.control.NumericEditField
        RunButton                   matlab.ui.control.Button
        TextAreaLabel               matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        FitnessAxes                 matlab.ui.control.UIAxes
        FunctionNameDropDownLabel   matlab.ui.control.Label
        FunctionNameDropDown        matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            result = "Started Optimizing " + app.FunctionNameDropDown.Value + newline;
            scout_bee_number = app.AgentEditField.Value; % Number of search agents
            % Name of the test function that can be from F1 to F19 
            % And cec01 t0 cec10 (Table 1,2 in the paper)
            function_name=app.FunctionNameDropDown.Value; 
            
            [dimensions,fitness,upper_bound, lower_bound] = Select_Functions(function_name);
            result = result + newline + "Dimensions: " + dimensions;
            result = result + newline + "lower_bound: " + num2str(lower_bound);
            result = result + newline + "upper_bound: " + num2str(upper_bound) + newline;
            
            %Fitness Dependent Optimizer 
            
            max_iteration = app.MaxIterationEditField.Value; 
            weightFactor = 0.0;  %equation 2 in the above paper.
            [best_fitness_value, best_scout_bee, FDO_curve] = FDO(scout_bee_number,max_iteration,lower_bound,upper_bound,dimensions,fitness, weightFactor);
            %fprintf("FDO Fitness %f \n",best_fitness_value);
            %plot(FDO_curve);
            result = result  + newline +  "FDO Fitness: " + best_fitness_value ;
            
            
            %Genetic Algorithm Optimization
            global xx;
            xx = [];
            options_ga = optimoptions('ga','MaxGenerations',max_iteration*6,"PopulationSize",scout_bee_number,'OutputFcn',@plotfun_ga);

            [x, fval, exitflag, output, population, scores] = ga(fitness,dimensions,[],[],[],[],lower_bound,upper_bound,[],options_ga);

            %fprintf("Genetic Fitness %f \n",fval);
            
            result = result + newline + "Genetic Fitness: " + fval ;
            
            %Dragonfly Optimization Algorithm
            

            [Best_score_da,Best_pos_da,DA_cg_curve] = DA(scout_bee_number,max_iteration,lower_bound,upper_bound,dimensions,fitness);
            %fprintf("Dragonfly Fitness %f \n",Best_score_da);
            result = result  + newline +  "Dragonfly Fitness: " + Best_score_da ;
            
            %Salp Swarm Optimization Algorithm
            
            %addpath("E:\SASTRA\7th Sem\Fitness Dependent Optimizer Mini-Project\Work\SSA")  
            [Best_score_salp,Best_pos_salp,SSA_cg_curve]=SSA(scout_bee_number,max_iteration,lower_bound,upper_bound,dimensions,fitness);
            %fprintf("Salp Swarm Fitness %f \n",Best_score_salp);
            result = result + newline + "Salp Swarm Fitness: " + Best_score_salp + newline ;
            %rmpath("E:\SASTRA\7th Sem\Fitness Dependent Optimizer Mini-Project\Work\SSA")
            
            optimizers = ["Fitness Dependent Optimizer","Genetic Algorithm", "Dragonfly Algorithm", "Salp Swarm Algorithm"];
            fitnesses = [best_fitness_value,fval,Best_score_da,Best_score_salp];
            best_positions = [best_scout_bee.xs; x; Best_pos_da'; Best_pos_salp];
            
            [mini_fit,index1] = min(fitnesses);
            result = result + newline + "Best Optimizer: " + optimizers(index1);
            result = result + newline + "Best Fitness Value: " + mini_fit;
            result = result + newline + "Best Position: " + newline + num2str(best_positions(index1,:));

            app.TextArea.Value = result;
            
            plot(app.FitnessAxes,FDO_curve);
            hold(app.FitnessAxes,'on');
            plot(app.FitnessAxes,xx);
            plot(app.FitnessAxes,DA_cg_curve);
            plot(app.FitnessAxes,SSA_cg_curve);
            title(app.FitnessAxes,"Fitness Curve");
            xlabel(app.FitnessAxes,"Iteration");
            ylabel(app.FitnessAxes,"Fitness value");
            legend(app.FitnessAxes,"FDO","GA","DA","SSA");
            hold(app.FitnessAxes,'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AgentEditFieldLabel = uilabel(app.UIFigure);
            app.AgentEditFieldLabel.Text = 'Agent';
            app.AgentEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgentEditField.Value = 30;
            app.MaxIterationEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationEditFieldLabel.Text = 'Max Iteration';
            app.MaxIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterationEditField.Value = 100;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.FitnessAxes = uiaxes(app.UIFigure);
            title(app.FitnessAxes, 'Fitness Value Graph')
            xlabel(app.FitnessAxes, 'Iteration')
            ylabel(app.FitnessAxes, 'Fitness Value')
            app.FunctionNameDropDownLabel = uilabel(app.UIFigure);
            app.FunctionNameDropDownLabel.Text = 'Function Name';
            app.FunctionNameDropDown = uidropdown(app.UIFigure);
            app.FunctionNameDropDown.Items = {'FM', 'F1', 'F2', 'F3', 'F4', 'F5', 'F6', 'F7', 'F8', 'F9', 'F10', 'F11', 'F12', 'F13', 'F14', 'F15', 'F16', 'F17', 'F18', 'F19', 'F20', 'F21', 'F22', 'F23', 'cec01', 'cec02', 'cec03', 'cec04', 'cec05', 'cec06', 'cec07', 'cec08', 'cec09', 'cec10'};
            app.FunctionNameDropDown.Value = 'F1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptimizationAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end